classdef DetectDrone < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Panel_4                       matlab.ui.container.Panel
        show                          matlab.ui.control.UIAxes
        Panel_3                       matlab.ui.container.Panel
        ModeLabel                     matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        SaveyourrecordingLabel        matlab.ui.control.Label
        ImportanaudiofileLabel        matlab.ui.control.Label
        ExtractingsoundfeaturesLabel  matlab.ui.control.Label
        ClearthecurrentresultsLabel   matlab.ui.control.Label
        EndthecurrentrecordingLabel   matlab.ui.control.Label
        StartaudioscanLabel           matlab.ui.control.Label
        UploadAudioButton             matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        StopRecordingButton           matlab.ui.control.Button
        AnalyzeButton                 matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
        StartRecordingButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        s                           % בלי טיפוס מוגדר מראש
        audioData                               double
        isRecording                             logical = false
        isScanning                              logical = false
        AzimuthValues                           double = 0:180:180
        ElevationValues                         double = 0:20:90
        currentAzimuthIdx                       double = 1 
        currentElevationIdx                     double = 1 
        arduinoPort 
        dominantFrequencies
        spectralCentroid
        spectralRolloff
        scanTimer                               timer
        Camera
        vid
        animTimer                               timer     
        tAnim                                   double = 0
    end
    
    methods (Access = private)
        function startupFcn(app)
                imaqreset; % ניקוי מצלמות קיימות
                  
                try
                    app.vid = videoinput('winvideo', 2, 'MJPG_640x480');
                    src = getselectedsource(app.vid);
                    app.vid.FramesPerTrigger = 1;
                    app.vid.TriggerRepeat = 0;
                    triggerconfig(app.vid, 'manual');
                    initializeSystem(app); 
                catch ME
                    uialert(app.UIFigure, "אירעה שגיאה בפתיחת המצלמה: " + ME.message, "שגיאה");
                end
        end
              
        
            function initializeSystem(app)
                try
                    % אתחול DAQ
                    app.s = daq("ni");
                    ch = addinput(app.s, "Dev1", "ai1", "Voltage");
            
                    % הגדרות ערוץ
                    ch.TerminalConfig = "SingleEnded";  % או "Differential" לפי הצורך
                    ch.Range = [-0.5, 0.5];             % טווח המדידה
            
                    % הגדרות רכישת נתונים
                    app.s.Rate = 50000;
                    app.audioData = [];
                    app.s.ScansAvailableFcnCount = 5000;
                    app.s.ScansAvailableFcn = @(src, event) app.collectData(src, event);
            
                    % אתחול Arduino
                    app.arduinoPort = serialport("COM5", 9600);
                    pause(2); % המתנה לאתחול
                    temp = 'good initialize system'
                catch ME
                    app.ModeLabel.Text = "⚠️ Failed to initialize system.";
                    rethrow(ME);
                end
            end


            function img = takePhoto(app)
                H= 'photo'
                img = []; % החזר ברירת מחדל אם יש שגיאה
                try
                    if isempty(app.vid) || ~isvalid(app.vid)
                        uialert(app.UIFigure, "המצלמה לא מחוברת או לא תקינה.", "שגיאה");
                        return;
                    end
            
                    imaqreset;
                
                    % התחברות למצלמה
                    app.vid = videoinput('winvideo', 2, 'MJPG_640x480');
                    app.vid.FramesPerTrigger = 1;
                    start(app.vid);
                    wait(app.vid);
                    
                    % קבלת התמונה
                    img = getdata(app.vid);
                    
                    % הצגת התמונה ב-Axes של האפליקציה
                    imshow(img, 'Parent', app.show); % שינוי השם בהתאם ל-Axes שלך
                    
                    % ניקוי המשאבים
                    stop(app.vid);
                    delete(app.vid);
                    clear app.vid;

                    
                    if ~isempty(img)
                        imshow(img, 'Parent', app.show); % ודא app.show הוא Axes תקין
                    else
                        uialert(app.UIFigure, "לא התקבלה תמונה מהמצלמה.", "שגיאה");
                    end
            
                catch ME
                    uialert(app.UIFigure, "אירעה שגיאה בעת צילום התמונה: " + ME.message, "שגיאה");
                end
            end

            function updateRecordingAnimation(app)
                a= 'HI'
                % עדכון הזמן
                app.tAnim = app.tAnim + 0.1;
            
                % רדיוס משתנה
                radius = 20 + 10 * sin(2 * pi * 0.5 * app.tAnim);
            
                % צבע משתנה לפי הזמן
                brightness = 0.5 + 0.5 * sin(2 * pi * 0.5 * app.tAnim);
                color = [0.2, 0.5, 1] * brightness;
            
                % עיגול
                theta = linspace(0, 2*pi, 100);
                x = radius * cos(theta);
                y = radius * sin(theta);
            
                % ציור בעזרת fill
                cla(app.show);                    % מחיקת ציורים קודמים
                hold(app.show, 'on');            % חשוב!
                fill(app.show, x, y, color, ...
                    'EdgeColor', 'none', ...
                    'FaceAlpha', 0.5);
                hold(app.show, 'off');
            
                % קביעת גבולות וצירים
                axis(app.show, 'equal');
                xlim(app.show, [-40, 40]);
                ylim(app.show, [-40, 40]);
                app.show.XColor = 'none';
                app.show.YColor = 'none';
            end


        function collectData(app, src, event)
                disp("Collecting...");  % לעקוב אחרי פעילות
                data = read(src, src.ScansAvailableFcnCount, "OutputFormat", "Matrix");
                app.audioData = [app.audioData; data];
        end


        function sendServoCommand(app, Azimuth, Elevation)
                command = uint8([Azimuth, Elevation, 0, 0]); % סרוו 3 ו־4 נשארים 0
                write(app.arduinoPort, command, 'uint8');
        end
        
         % Start recording
        function startRecording(app)
            app.audioData = [];
            try
                start(app.s, "Continuous");
                app.isRecording = true;
                app.ModeLabel.Text = "🔴 Recording...";
                app.tAnim = 0;
                disp('Creating timer...');
                app.animTimer = timer( ...
                    'ExecutionMode', 'fixedRate', ...
                    'Period', 0.1, ...
                    'TimerFcn', @(~,~) app.updateRecordingAnimation() ...
                );
                disp('Timer created, starting...');
                start(app.animTimer);
                disp('Timer started');


            catch ME
                app.ModeLabel.Text = "⚠️ Error: Could not start recording.";
                disp(ME.message);
 
            end
        end


        function stopRecording(app)
                    if app.isRecording
                        stop(app.s);
                        app.isRecording = false;
                        app.ModeLabel.Text = "⛔ Stopped.";

                                % עצירת האנימציה
                        if isvalid(app.animTimer)
                            stop(app.animTimer);
                            delete(app.animTimer);
                        end
                        cla(app.show);  % ניקוי העיגול
                    end
        end
        % Stop recording
        function stopAll(app)
            % עצירת הקלטה אם פעילה
            if app.isRecording
                stop(app.s);
                app.isRecording = false;
            end
        
            % עצירת סריקה אם פעילה
            if app.isScanning
                app.isScanning = false;
                if isvalid(app.scanTimer)
                    stop(app.scanTimer);
                    delete(app.scanTimer);
                end
            end
        
            % סגירת המצלמה אם היא פתוחה ותקינה
            if isprop(app, 'vid') && ~isempty(app.vid) && isvalid(app.vid)
                try
                    stop(app.vid);
                    delete(app.vid);
                    app.vid = [];
                catch ME
                    % אפשר ללוג או להציג שגיאה אם רוצים
                    warning("Failed to stop and delete video input: " + ME.message);
                end
            end
        
            % עדכון התווית למצב עצור
            app.ModeLabel.Text = "⛔ All stopped.";
        end

        function startScanning(app)
            app.isScanning = true;
            app.currentAzimuthIdx = 1;
            app.currentElevationIdx = 1;
            
            % שליחת פקודת התחלה ראשונית לסרוו
            Azimuth = app.AzimuthValues(app.currentAzimuthIdx);
            Elevation = app.ElevationValues(app.currentElevationIdx);
            app.sendServoCommand(Azimuth, Elevation);
            
            t = timer( ...
                'ExecutionMode', 'fixedSpacing', ...
                'Period', 10, ... 
                'TimerFcn', @(~,~) app.scanStep() ...
            );
            
            app.scanTimer = t;
            start(t);
            %app.ModeLabel.Text = "🛑 Start scanning";
        end


        function shouldReturn = stopScanIfNeeded(app)
            shouldReturn = false;
            if ~app.isScanning
                if isvalid(app.scanTimer)
                    stop(app.scanTimer);
                    delete(app.scanTimer);
                end
                app.ModeLabel.Text = "⛔Stop scanning";
                shouldReturn = true;
            end
        end

        function scanStep(app)
        if stopScanIfNeeded(app)
            return;
        end
            
            Azimuth = app.AzimuthValues(app.currentAzimuthIdx);
            Elevation = app.ElevationValues(app.currentElevationIdx);
            % שליחת פקודת סרוו
            app.sendServoCommand(Azimuth, Elevation);
            pause(0.5); % זמן למנועים להגיע לזווית
            if stopScanIfNeeded(app)
                return;
            end
            
            % התחלת הקלטה
            app.startRecording();
            pause(5);
            if stopScanIfNeeded(app)
                return;
            end

            app.stopRecording();
            if stopScanIfNeeded(app)
                return;
            end
            
            % ניתוח האות
            isDrone = app.analyze();
            if stopScanIfNeeded(app)
                return;
            end
            
            if isDrone
                app.isScanning = false;
                stop(app.scanTimer);
                delete(app.scanTimer);
                app.ModeLabel.Text = sprintf("✅ Drone detected at azimuth %d°, elevation %d°. Scanning stopped", Azimuth, Elevation);
                uialert(app.UIFigure, 'Drone Detected!', 'Result');
                % כאן תשלח פקודה לגימבל המצלמה
                % app.sendGimbalCommand(Azimuth, Elevation);
                app.takePhoto();  % תצלם ותציג את התמונה ב-Axes


            else
                % לא זוהה רחפן, מתקדם לזווית הבאה
                app.ModeLabel.Text = sprintf("❌ No target detected at azimuth %d°, elevation %d°. Scan continuing", Azimuth, Elevation);
                app.advanceScanIndices();
            end
        end

        
        function advanceScanIndices(app)
            app.currentAzimuthIdx = app.currentAzimuthIdx + 1;
        
            if app.currentAzimuthIdx > length(app.AzimuthValues)
                app.currentAzimuthIdx = 1;
                app.currentElevationIdx = app.currentElevationIdx + 1;
        
                if app.currentElevationIdx > length(app.ElevationValues)
                    app.currentElevationIdx = 1; % חזרה לתחילת הסריקה
                end
            end
        end


        % Save the recorded data
        function saveRecording(app)
            if isempty(app.audioData)
                app.ModeLabel.Text = "⚠️ No data to save.";
                return;
            end
            disp(['Sampling Rate: ', num2str(app.s.Rate)]);
            timestamp = datetime('now', 'Format', 'yyyyMMdd_HHmmss');
            filename = ['drone_' char(timestamp) '.wav'];
            audiowrite(filename, app.audioData, round(app.s.Rate));
            app.ModeLabel.Text = ['💾 Saved as ' filename];
        end


        % Reset the application state
        function resetApp(app)
            disp(">>> resetApp function called");
                % עצירת הקלטה אם פעילה
                if isprop(app, 'isRecording') && app.isRecording
                    try
                        stop(app.s);  % עצור את ה־DAQ
                    catch err
                        disp("⚠️ Error while stopping DAQ: " + err.message);
                    end
                    app.isRecording = false;
                end
            
                % איפוס נתונים
                app.audioData = [];
                app.dominantFrequencies = [];
                app.spectralCentroid = [];
                app.spectralRolloff = [];
            
                % עדכון תוויות
                app.ModeLabel.Text = "Reset. Mode.";
            
                % איפוס כפתורים
                app.StartRecordingButton.Enable = true;
                app.StopRecordingButton.Enable = true;
                app.SaveButton.Enable = false;
                app.UploadAudioButton.Enable = true;
                app.AnalyzeButton.Enable = false;
                app.ResetButton.Enable = false;
            
                % מחיקת אובייקט DAQ אם קיים ותקף
                try
                    if isprop(app, 's') && ~isempty(app.s) && isa(app.s, 'daq')  % בדיקה אם app.s הוא אובייקט daq
                        delete(app.s);
                        app.s = [];  % נקה את המשתנה
                    end
                catch err
                    app.ModeLabel.Text = "⚠️ Error during reset.";
                    disp("⚠️ Error while deleting app.s: " + err.message);
                end

        end 

       function [mostFrequentDominantFreqs, P1Modes] = computeDominantFrequencies(~, audioData, numWindows, sampleRate)
                % פונקציה לחישוב תדרים דומיננטיים לכל חלון של אודיו באמצעות FFT
                % אורך האות
                L = length(audioData);
                % הגדרת גודל חלון
                frameLength = floor(L / numWindows); % גודל כל חלון
            
                % חפיפה של 50%
                hopSize = frameLength / 2;
            
                % חישוב מספר החלונות
                numFrames = floor((L - frameLength) / hopSize) + 1;
            
                % יצירת מערך לשמירת החלונות
                frames = zeros(frameLength, numFrames);
            
                % חלוקה לחלונות
                for i = 1:numFrames
                    startIdx = (i - 1) * hopSize + 1; % מציאת האינדקס ההתחלתי לכל חלון
                    endIdx = startIdx + frameLength - 1; % מציאת האינדקס הסופי של כל חלון
                    frames(:, i) = audioData(startIdx:endIdx); % שמירת החלון במערך
                end
            
                % חישוב FFT לכל חלון
                halfFrameLength = floor(frameLength / 2) + 1; % לוודא שהגודל הוא שלם
                P1Modes = zeros(halfFrameLength, numFrames); % ספקטרום חד-צדדי לכל חלון
                dominantFrequencies = []; % רשימה לאחסון התדרים הדומיננטיים
            
                for i = 1:numFrames
                    frame = frames(:, i); % בחירת חלון i
            
                    % חישוב FFT של כל חלון
                    fftFrame = fft(frame); 
                    P2 = abs(fftFrame / frameLength); % ספקטרום דו-צדדי
                    P1 = P2(1:halfFrameLength); % ספקטרום חד-צדדי
                    P1(2:end-1) = 2*P1(2:end-1); % התאמה לספקטרום חד-צדדי
            
                    % יצירת וקטור תדרים
                    f = sampleRate * (0:(halfFrameLength-1)) / frameLength; 
            
                    % שמירה בספקטרום חד-צדדי
                    P1Modes(:, i) = P1;
            
                    % מציאת חמשת התדרים הדומיננטיים בחלון הזה
                    [~, idx] = maxk(P1, 5); % מקבלים את 5 התדרים עם האנרגיה הגבוהה ביותר
                    dominantFrequencies = [dominantFrequencies; f(idx)']; % שמירה של התדרים הדומיננטיים
                end
            
                % מוצאים את 5 התדרים הדומיננטיים ביותר לאורך כל החלונות
                allDominantFreqs = dominantFrequencies(:); % כל התדרים הדומיננטיים מכל החלונות
                [uniqueFreqs, ~, ic] = unique(allDominantFreqs); % מזהים את התדרים הייחודיים
                counts = accumarray(ic, 1); % סופרים כמה פעמים כל תדר הופיע
            
                [~, sortIdx] = maxk(counts, 1);
                mostFrequentDominantFreqs = uniqueFreqs(sortIdx);
       end


       function [spectralCentroids, centroidMean, centroidStd] = computeSpectralCentroid(app,audioData, numWindows, sampleRate)
                    % נרמול למניעת תקלות
                    audioData = audioData(:);
                    audioData = audioData / max(abs(audioData) + eps);
                
                    L = length(audioData);
                    frameLength = floor(L / numWindows);
                    hopSize = floor(frameLength / 2);
                    numFrames = floor((L - frameLength) / hopSize) + 1;
                
                    spectralCentroids = zeros(1, numFrames);
                
                    for i = 1:numFrames
                        startIdx = (i - 1) * hopSize + 1;
                        endIdx = startIdx + frameLength - 1;
                        frame = audioData(startIdx:endIdx) .* hann(frameLength);
                
                        fftFrame = abs(fft(frame));
                        fftFrame = fftFrame(1:floor(end/2));
                        freqs = linspace(0, sampleRate/2, length(fftFrame));
                
                        if sum(fftFrame) > 0
                            spectralCentroids(i) = sum(freqs .* fftFrame') / sum(fftFrame);
                        else
                            spectralCentroids(i) = NaN; % אם אין אנרגיה
                        end
                    end
                
                    % הסרת NaN לפני ממוצע
                    spectralCentroids = spectralCentroids(~isnan(spectralCentroids));
                
                    centroidMean = mean(spectralCentroids);
                    centroidStd = std(spectralCentroids);
                
                    fprintf('--- Spectral Centroid ---\nMean: %.2f Hz\nStd: %.2f Hz\n', centroidMean, centroidStd);
       end


       function [rolloffPoints, rolloffMean, rolloffStd] = computeSpectralRolloff(app,audioData, numWindows, sampleRate)
                % אורך האות
                L = length(audioData);
            
                % אורך כל חלון
                frameLength = floor(L / numWindows);
                hopSize = frameLength / 2;
            
                % מספר החלונות בפועל
                numFrames = floor((L - frameLength) / hopSize) + 1;
            
                % יצירת מטריצת חלונות
                frames = zeros(frameLength, numFrames);
                for i = 1:numFrames
                    startIdx = (i - 1) * hopSize + 1;
                    endIdx = startIdx + frameLength - 1;
                    frames(:, i) = audioData(startIdx:endIdx);
                end
            
                % אתחול מערך לנקודות הרול-אוף
                rolloffPoints = zeros(1, numFrames);
            
                % חישוב רול-אוף עבור כל חלון
                for i = 1:numFrames
                    frame = frames(:, i) .* hann(frameLength); % חלון הנינג
            
                    % ספקטרום
                    fftFrame = abs(fft(frame));
                    fftFrame = fftFrame(1:floor(end/2));
                    freqs = linspace(0, sampleRate/2, length(fftFrame));
            
                    % חישוב רול-אוף
                    totalEnergy = sum(fftFrame.^2);
                    cumulativeEnergy = cumsum(fftFrame.^2);
                    energyThreshold = 0.95 * totalEnergy;
                    rolloffIdx = find(cumulativeEnergy >= energyThreshold, 1, 'first');
                    rolloffPoints(i) = freqs(rolloffIdx);
                end
            
                % חישוב ממוצע וסטיית תקן
                rolloffMean = mean(rolloffPoints);
                rolloffStd = std(rolloffPoints);
            
        end

            
       function features = extractAudioFeatures( app,audioData, numWindows, sampleRate)
                        % חילוץ תכונות אודיו עיקריות מדגימה בודדת של אות
                        % פלט:
                        % features - וקטור תכונות: [dominantFreq, harmonicMean, rolloffMean]
                    
                        mostFrequentDominantFreqs = app.computeDominantFrequencies(audioData, numWindows, sampleRate)
                  
                        [~, centroidMean, ~] = app.computeSpectralCentroid(audioData, numWindows, sampleRate)
                    
                        [~, rolloffMean, ~] = app.computeSpectralRolloff(audioData, numWindows, sampleRate)
                        disp( mostFrequentDominantFreqs)
                        disp( centroidMean)
                        disp( rolloffMean)
                    
                     
                        % הרכבת וקטור תכונות
                        features = [mostFrequentDominantFreqs(:)' centroidMean rolloffMean];
       end

       function Mode = analyze(app)
                 disp('analyze started');
    
                app.ModeLabel.Text = sprintf("Analyze");
                drawnow;
                pause(0.1)
                
            
                audioData = app.audioData;
            
                if length(audioData) < 1024
                    app.ModeLabel.Text = "⚠️ Audio too short to analyze.";
                    return;
                end
            
                if isempty(app.s) || ~isprop(app.s, 'Rate')
                    app.ModeLabel.Text = "⚠️ Sampling session not initialized.";
                    return;
                end
            
                sampleRate = app.s.Rate;
                numWindows = 512;
                features = app.extractAudioFeatures(audioData, numWindows, sampleRate);
            
                if isempty(features) || length(features) < 3
                    app.ModeLabel.Text = "⚠️ Feature extraction failed.";
                    return;
                end
            
                % תחום תדרים צפוי לרחפן
                minFreq = 50;       % 0.0177*10^4
                maxFreq = 11000;
            
                minCentroid = 0;
                maxCentroid = 4500;
            
                minRollOff = 0;
                maxRollOff = 4200;
            
                % שליפת תכונות
                dominantFreq = features(1);
                centroid = features(2);
                rollOff = features(3);
            
                % בדיקות
                withinFreq = dominantFreq >= minFreq && dominantFreq <= maxFreq;
                withinCentroid = centroid >= minCentroid && centroid <= maxCentroid;
                withinRollOff = rollOff >= minRollOff && rollOff <= maxRollOff;
            
                Mode = withinFreq && withinCentroid && withinRollOff;
            
                if Mode
           
                   
                else
                    app.ModeLabel.Text = "❌ No Drone Detected.";
                   
                end
            
            end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartRecordingButton
        function StartRecordingButtonPushed(app, event)
                startupFcn(app)
                app.startScanning();
        end

        % Button pushed function: StopRecordingButton
        function StopRecordingButtonPushed(app, event)
                disp(">>> Stop button pressed");
                stopRecording(app)
                stopAll(app);
                app.AnalyzeButton.Enable = true;
                app.SaveButton.Enable = true; 
                app.ResetButton.Enable = true;
         
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            disp(">>> resetApp function called");
            resetApp(app)
        end

        % Button pushed function: UploadAudioButton
        function UploadAudioButtonPushed(app, event)
                setupDAQ(app);
                app.AnalyzeButton.Enable = true;
                app.SaveButton.Enable = true;
                
                % Open file dialog to select MP3 or WAV files
                [fileName, filePath] = uigetfile({'*.mp3;*.wav', 'Audio Files (*.mp3, *.wav)'}, 'Select an MP3 or WAV file');
                
                if isequal(fileName, 0)
                    app.ModeLabel.Text = "⚠️ No file selected.";
                    uialert(app.UIFigure, 'No file was selected.', 'Upload');
                    return;
                end
                
                % Get file extension
                [~, ~, ext] = fileparts(fileName);
                
                if strcmpi(ext, '.mp3')
                    % Create WAV filename based on MP3 filename
                    [~, name, ~] = fileparts(fileName);
                    wavFileName = fullfile(filePath, [name '.wav']);
                    
                    try
                        % Read MP3 file
                        [audioData, sampleRate] = audioread(fullfile(filePath, fileName));
                        
                        % Save as WAV file
                        audiowrite(wavFileName, audioData, sampleRate);
                        app.audioData = audioData;
                        app.s.Rate = sampleRate; % Update sampling rate
                        disp(size(app.audioData));
                        disp(app.s.Rate);
                
                        app.ModeLabel.Text = ['📂 File uploaded and saved as: ' wavFileName];
                        uialert(app.UIFigure, ['MP3 file uploaded and saved as WAV: ' wavFileName], 'Upload Success');
                    catch ME
                        app.ModeLabel.Text = "⚠️ Error during MP3 to WAV conversion.";
                        disp(ME.message);
                        uialert(app.UIFigure, 'Error during MP3 to WAV conversion.', 'Error');
                    end
                elseif strcmpi(ext, '.wav')
                    try
                        % Read WAV file
                        [audioData, sampleRate] = audioread(fullfile(filePath, fileName));
                        app.audioData = audioData;
                        app.s.Rate = sampleRate; % Update sampling rate
                        
                        app.ModeLabel.Text = ['📂 WAV file uploaded: ' fileName];
                        uialert(app.UIFigure, ['WAV file uploaded successfully: ' fileName], 'Upload Success');
                    catch ME
                        app.ModeLabel.Text = "⚠️ Error during WAV file loading.";
                        disp(ME.message);
                        uialert(app.UIFigure, 'Error loading WAV file.', 'Error');
                    end
                else
                    app.ModeLabel.Text = "⚠️ Unsupported file format.";
                    uialert(app.UIFigure, 'Unsupported file format.', 'Error');
                end

        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            app.ResetButton.Enable = true;
            if isempty(app.audioData)
                app.ModeLabel.Text = "⚠️ No audio data to analyze.";
                return;
            end
            try
                app.analyze();
            catch ME
                app.ModeLabel.Text = "⚠️ Error during analysis.";
                disp(ME.message);
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
                saveRecording(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.StartRecordingButton = uibutton(app.Panel, 'push');
            app.StartRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordingButtonPushed, true);
            app.StartRecordingButton.Icon = fullfile(pathToMLAPP, 'Record.png');
            app.StartRecordingButton.Tooltip = {'Start audio scan'};
            app.StartRecordingButton.Text = 'Start Recording';
            app.SaveButton = uibutton(app.Panel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = fullfile(pathToMLAPP, 'Save.png');
            app.SaveButton.Text = 'Save';
            app.AnalyzeButton = uibutton(app.Panel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.AnalyzeButton.Icon = fullfile(pathToMLAPP, 'icons8-analyze-64 (1).png');
            app.StopRecordingButton = uibutton(app.Panel, 'push');
            app.StopRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StopRecordingButtonPushed, true);
            app.StopRecordingButton.Text = 'Stop Recording';
            app.StopRecordingButton.Icon = fullfile(pathToMLAPP, 'icons8-stop-50.png');
            app.ResetButton = uibutton(app.Panel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Icon = fullfile(pathToMLAPP, 'Reset.png');
            app.ResetButton.Text = 'Reset';
            app.UploadAudioButton = uibutton(app.Panel, 'push');
            app.UploadAudioButton.ButtonPushedFcn = createCallbackFcn(app, @UploadAudioButtonPushed, true);
            app.UploadAudioButton.Icon = fullfile(pathToMLAPP, 'upload.png');
            app.UploadAudioButton.Text = 'Upload Audio';
            app.StartaudioscanLabel = uilabel(app.Panel);
            app.StartaudioscanLabel.Text = 'Start audio scan';
            app.EndthecurrentrecordingLabel = uilabel(app.Panel);
            app.EndthecurrentrecordingLabel.Text = 'End the current recording';
            app.ClearthecurrentresultsLabel = uilabel(app.Panel);
            app.ClearthecurrentresultsLabel.Text = 'Clear the current results';
            app.ExtractingsoundfeaturesLabel = uilabel(app.Panel);
            app.ExtractingsoundfeaturesLabel.Text = 'Extracting sound features';
            app.ImportanaudiofileLabel = uilabel(app.Panel);
            app.ImportanaudiofileLabel.Text = 'Import an audio file';
            app.SaveyourrecordingLabel = uilabel(app.Panel);
            app.SaveyourrecordingLabel.Interpreter = 'html';
            app.SaveyourrecordingLabel.Text = {'Save your recording'; ''};
            app.Panel_3 = uipanel(app.UIFigure);
            app.ModeLabel = uilabel(app.Panel_3);
            app.ModeLabel.Text = 'Mode';
            app.Panel_4 = uipanel(app.UIFigure);
            app.show = uiaxes(app.Panel_4);
            title(app.show, 'Title')
            xlabel(app.show, 'X')
            ylabel(app.show, 'Y')
            zlabel(app.show, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DetectDrone

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end